classdef ReaStreamReceiverApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        udpBufferSizeCheckBox          matlab.ui.control.CheckBox
        Button                         matlab.ui.control.Button
        ReaStreamIdentifierDropDown    matlab.ui.control.DropDown
        ReaStreamIdentifierDropDownLabel  matlab.ui.control.Label
        UseScriptENGINEratherthantheclassENGINECheckBox  matlab.ui.control.CheckBox
        FlushUDPBufferButton           matlab.ui.control.Button
        STOPButton                     matlab.ui.control.Button
        Gauge_R                        matlab.ui.control.SemicircularGauge
        Gauge_L                        matlab.ui.control.SemicircularGauge
        DeviceDropDown                 matlab.ui.control.DropDown
        DeviceDropDownLabel            matlab.ui.control.Label
        AudioDriverDropDown            matlab.ui.control.DropDown
        AudioDriverDropDownLabel       matlab.ui.control.Label
        DebugONCheckBox                matlab.ui.control.CheckBox
        ScopeCheckBox                  matlab.ui.control.CheckBox
        VariableBufferCheckBox         matlab.ui.control.CheckBox
        ReceiverIPEditField            matlab.ui.control.EditField
        IPEditFieldLabel               matlab.ui.control.Label
        ReceiverPortEditField          matlab.ui.control.NumericEditField
        PortEditFieldLabel             matlab.ui.control.Label
        FPSEditField                   matlab.ui.control.NumericEditField
        FPSEditFieldLabel              matlab.ui.control.Label
        MeanNumBytesAvailableTextArea  matlab.ui.control.TextArea
        MeanNumBytesAvailableTextAreaLabel  matlab.ui.control.Label
        PlaybackSampleSizeEditField    matlab.ui.control.NumericEditField
        PlaybackSampleSizeEditFieldLabel  matlab.ui.control.Label
        ConnectSwitch                  matlab.ui.control.Switch
        ConnectSwitchLabel             matlab.ui.control.Label
        Image                          matlab.ui.control.Image
    end

    
    properties (Access = public)
        lastStringIND = 1;
        
        scope = []; % Description
        scopeSetupDone_FLAG = false; % Description
        debugFields = {'MeanNumBytesAvailableTextArea','MeanNumBytesAvailableTextAreaLabel',...
                       'FPSEditField','FPSEditFieldLabel','udpBufferSizeCheckBox','ScopeCheckBox'};
        UPD_bufferSizes = zeros(round(48000/128),1);
               
        ax = []; % Description
        
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: ConnectSwitch
        function ConnectSwitchValueChanged(app, event)
            if strcmpi(app.ConnectSwitch.Value,'On')
                % Clear display
                clc;           
                
                % My app handle is used for UI meter updates and debuggin
                global DEBUG_ON_LAST_STATE_FLAG_  output_ myApp;
                myApp = app;
                output_ = [];% DEBUG DATA
                % For debuggin purposes
                DEBUG_ON_LAST_STATE_FLAG_ = false;  


                %% INTERUPT FLAG
                global STATE_IN_LOOP_FLAG_;               
                STATE_IN_LOOP_FLAG_ = true;
                
                % UI LOCK
                app.ConnectSwitch.Enable = false;
                % Select all UI elements to be locked during playback
                editableElements = {app.ReceiverIPEditField;
                                    app.ReceiverPortEditField;
                                    app.ReaStreamIdentifierDropDown;
                                    app.AudioDriverDropDown;
                                    app.DeviceDropDown;
                                    app.PlaybackSampleSizeEditField;};
                % Lock UI elements
                if not(app.DebugONCheckBox.Value)
                    for f = 1:numel(editableElements)
%                         editableElements{f}.Editable = false;
                        editableElements{f}.Enable = false;
                    end
                end
                
                % Reset Gauges 
                app.Gauge_L.Value = -100;
                app.Gauge_R.Value = -100;
                
                if app.UseScriptENGINEratherthantheclassENGINECheckBox.Value
                    % Main process script
                    MainReceptionProcess(myApp);
                else
                    receiver = ReaStreamReceiver(app);
                    receiver.runMainReceptionProcess();
                end
                
                % Hide debug settings and close scope
                if app.DebugONCheckBox.Value
                    for ff = 1:numel(app.debugFields)
                        app.(app.debugFields{ff}).Visible = false;
                    end
                    if app.scopeSetupDone_FLAG && isvalid(app.scope)
                        disp(' --- Close scope! --- ');
                        release(app.scope);
                        app.scopeSetupDone_FLAG = false;
                    end
                end
                
                % Reset Gauges 
                app.Gauge_L.Value = -100;
                app.Gauge_R.Value = -100;
                
                % Reset UI
                app.ConnectSwitch.Enable = true;
                for f = 1:numel(editableElements)
%                         editableElements{f}.Editable = true;
                        editableElements{f}.Enable = true;
                end
                
                
            end
        end

        % Value changed function: AudioDriverDropDown
        function AudioDriverDropDownValueChanged(app, event)
            % Get currect driver option
            value = app.AudioDriverDropDown.Value;
            
            % Make bind temporary to the default device
            tmpDeviceWriter = audioDeviceWriter('Driver',value);
            % Get all device options
            devices = getAudioDevices(tmpDeviceWriter)';
            % Populate the device option list in the UI
            app.DeviceDropDown.Items = devices;
            
            % Release the temporary device binding
            release(tmpDeviceWriter)
        end

        % Button pushed function: STOPButton
        function STOPButtonPushed(app, event)
                global STATE_IN_LOOP_FLAG_;
                STATE_IN_LOOP_FLAG_ = false;
                % Reset UI
                app.ConnectSwitch.Value = 'Off';
                app.ConnectSwitch.Enable = true;
        end

        % Button pushed function: FlushUDPBufferButton
        function FlushUDPBufferButtonPushed(app, event)
            global FLUSH_UDP_BUFFER_FLAG_;
            FLUSH_UDP_BUFFER_FLAG_ = true;
        end

        % Value changed function: ReaStreamIdentifierDropDown
        function ReaStreamIdentifierDropDownValueChanged(app, event)
            value = app.ReaStreamIdentifierDropDown.Value;
               
%             if ismember(value,app.ReaStreamIdentifierDropDown.Items(1:end-1))
%                 app.lastStringIND = find(ismember(value,app.ReaStreamIdentifierDropDown.Items(1:end-1)));
%                 return;
%             elseif ismember(value,app.ReaStreamIdentifierDropDown.Items(end))
%                 2
%             else
%                 3
%                 app.ReaStreamIdentifierDropDown.Value = app.ReaStreamIdentifierDropDown.Items{app.lastStringIND};                
%             end
            
            
%             if ~ismember(value,app.ReaStreamIdentifierDropDown.Items(1:end-1))
%                 app.ReaStreamIdentifierDropDown.Items{app.lastStringIND} = value; 
%                 app.ReaStreamIdentifierDropDown.ItemsData{app.lastStringIND} = value; 
%             end


            %             app.ReaStreamIdentifierDropDown.Value = app.ReaStreamIdentifierDropDown.Items{app.lastStringIND};
            %             app.ReaStreamIdentifierDropDown.Value = app.ReaStreamIdentifierDropDown.Items{app.lastStringIND};
%             app.ReaStreamIdentifierDropDown.Editable = false;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'nga_workshop_wave_833x469.jpg';
            app.ConnectSwitchLabel = uilabel(app.UIFigure);
            app.ConnectSwitchLabel.Text = 'Connect';
            app.ConnectSwitch = uiswitch(app.UIFigure, 'slider');
            app.ConnectSwitch.ValueChangedFcn = createCallbackFcn(app, @ConnectSwitchValueChanged, true);
            app.PlaybackSampleSizeEditFieldLabel = uilabel(app.UIFigure);
            app.PlaybackSampleSizeEditFieldLabel.Text = 'Playback Sample Size';
            app.PlaybackSampleSizeEditField = uieditfield(app.UIFigure, 'numeric');
            app.PlaybackSampleSizeEditField.Limits = [0 Inf];
            app.PlaybackSampleSizeEditField.Value = 128;
            app.MeanNumBytesAvailableTextAreaLabel = uilabel(app.UIFigure);
            app.MeanNumBytesAvailableTextAreaLabel.Visible = 'off';
            app.MeanNumBytesAvailableTextAreaLabel.Text = 'Mean(NumBytesAvailable)=';
            app.MeanNumBytesAvailableTextArea = uitextarea(app.UIFigure);
            app.MeanNumBytesAvailableTextArea.Editable = 'off';
            app.MeanNumBytesAvailableTextArea.Visible = 'off';
            app.MeanNumBytesAvailableTextArea.Value = {'0'};
            app.FPSEditFieldLabel = uilabel(app.UIFigure);
            app.FPSEditFieldLabel.Visible = 'off';
            app.FPSEditFieldLabel.Text = 'FPS';
            app.FPSEditField = uieditfield(app.UIFigure, 'numeric');
            app.FPSEditField.Visible = 'off';
            app.FPSEditField.Value = 15;
            app.PortEditFieldLabel = uilabel(app.UIFigure);
            app.PortEditFieldLabel.Text = 'Port';
            app.ReceiverPortEditField = uieditfield(app.UIFigure, 'numeric');
            app.ReceiverPortEditField.Limits = [0 65535];
            app.ReceiverPortEditField.ValueDisplayFormat = '%8i';
            app.ReceiverPortEditField.Value = 58710;
            app.IPEditFieldLabel = uilabel(app.UIFigure);
            app.IPEditFieldLabel.Text = 'IP';
            app.ReceiverIPEditField = uieditfield(app.UIFigure, 'text');
            app.ReceiverIPEditField.Value = '0.0.0.0';
            app.VariableBufferCheckBox = uicheckbox(app.UIFigure);
            app.VariableBufferCheckBox.Text = 'Variable Buffer';
            app.VariableBufferCheckBox.Value = true;
            app.ScopeCheckBox = uicheckbox(app.UIFigure);
            app.ScopeCheckBox.Visible = 'off';
            app.ScopeCheckBox.Text = 'Scope';
            app.DebugONCheckBox = uicheckbox(app.UIFigure);
            app.DebugONCheckBox.Text = 'DebugON';
            app.AudioDriverDropDownLabel = uilabel(app.UIFigure);
            app.AudioDriverDropDownLabel.Text = 'Audio Driver';
            app.AudioDriverDropDown = uidropdown(app.UIFigure);
            app.AudioDriverDropDown.Items = {'DirectSound', 'ASIO', 'WASAPI'};
            app.AudioDriverDropDown.ValueChangedFcn = createCallbackFcn(app, @AudioDriverDropDownValueChanged, true);
            app.AudioDriverDropDown.Value = 'DirectSound';
            app.DeviceDropDownLabel = uilabel(app.UIFigure);
            app.DeviceDropDownLabel.Text = 'Device';
            app.DeviceDropDown = uidropdown(app.UIFigure);
            app.DeviceDropDown.Items = {'Default'};
            app.DeviceDropDown.Value = 'Default';
            app.Gauge_L = uigauge(app.UIFigure, 'semicircular');
            app.Gauge_L.Limits = [-60 0];
            app.Gauge_L.Value = -60;
            app.Gauge_R = uigauge(app.UIFigure, 'semicircular');
            app.Gauge_R.Limits = [-60 0];
            app.Gauge_R.ScaleDirection = 'counterclockwise';
            app.Gauge_R.Value = -60;
            app.STOPButton = uibutton(app.UIFigure, 'push');
            app.STOPButton.ButtonPushedFcn = createCallbackFcn(app, @STOPButtonPushed, true);
            app.STOPButton.Text = 'STOP';
            app.FlushUDPBufferButton = uibutton(app.UIFigure, 'push');
            app.FlushUDPBufferButton.ButtonPushedFcn = createCallbackFcn(app, @FlushUDPBufferButtonPushed, true);
            app.FlushUDPBufferButton.Text = 'Flush UDP Buffer';
            app.UseScriptENGINEratherthantheclassENGINECheckBox = uicheckbox(app.UIFigure);
            app.UseScriptENGINEratherthantheclassENGINECheckBox.Text = 'Use Script ENGINE rather than the class ENGINE';
            app.ReaStreamIdentifierDropDownLabel = uilabel(app.UIFigure);
            app.ReaStreamIdentifierDropDownLabel.Text = 'ReaStream Identifier ';
            app.ReaStreamIdentifierDropDown = uidropdown(app.UIFigure);
            app.ReaStreamIdentifierDropDown.Items = {'ReaperMainOut'};
            app.ReaStreamIdentifierDropDown.Editable = 'on';
            app.ReaStreamIdentifierDropDown.ValueChangedFcn = createCallbackFcn(app, @ReaStreamIdentifierDropDownValueChanged, true);
            app.ReaStreamIdentifierDropDown.Value = 'ReaperMainOut';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.Enable = 'off';
            app.Button.Visible = 'off';
            app.Button.Text = '-';
            app.udpBufferSizeCheckBox = uicheckbox(app.UIFigure);
            app.udpBufferSizeCheckBox.Visible = 'off';
            app.udpBufferSizeCheckBox.Text = 'UDP Buffer Size';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ReaStreamReceiverApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end